classdef A2_GUI_V3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        TeachLabel           matlab.ui.control.Label
        RunTaskLabel         matlab.ui.control.Label
        ZEditField           matlab.ui.control.NumericEditField
        ZEditFieldLabel      matlab.ui.control.Label
        YEditField           matlab.ui.control.NumericEditField
        YEditFieldLabel      matlab.ui.control.Label
        XEditField           matlab.ui.control.NumericEditField
        XEditFieldLabel      matlab.ui.control.Label
        Q6Slider             matlab.ui.control.Slider
        Q6SliderLabel        matlab.ui.control.Label
        Q5Slider             matlab.ui.control.Slider
        Q5SliderLabel        matlab.ui.control.Label
        Q4Slider             matlab.ui.control.Slider
        Q4SliderLabel        matlab.ui.control.Label
        Q3Slider             matlab.ui.control.Slider
        Q3SliderLabel        matlab.ui.control.Label
        Q2Slider             matlab.ui.control.Slider
        Q2SliderLabel        matlab.ui.control.Label
        Q1Slider             matlab.ui.control.Slider
        Q1SliderLabel        matlab.ui.control.Label
        NeutralButton        matlab.ui.control.Button
        ResumeButton         matlab.ui.control.Button
        StatusLamp           matlab.ui.control.Lamp
        StatusLampLabel      matlab.ui.control.Label
        StatusBar            slrealtime.ui.control.StatusBar
        EMERGENCYSTOPButton  matlab.ui.control.Button
        RunButton            matlab.ui.control.Button
    end

    
    properties (Access = private)
        RanimateInstance % Hold the RanimateTests object
    end
    
    methods (Access = public)
        
        function results = robot(app)

            
        end
    end
    
    methods (Access = private)
        % Button pushed function: LoadEnvButton
        function LoadEnvButtonPushed(app, event)
            % Initialize the environment (create the RanimateTests object)
            app.RanimateInstance = RanimateTests();  
        end

        % Button pushed function: MoveRobotButton
        function MoveRobotButtonPushed(app, event)
            % Call the moveTrash function to animate the robot
            if ~isempty(app.RanimateInstance)
                app.RanimateInstance.moveTrash();
            else
                uialert(app.UIFigure, 'Load the environment first!', 'Error');
            end
        end 
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: NeutralButton
        function NeutralButtonPushed(app, event)
            % Set lamp to idle state (blue) while loading
            app.StatusLamp.Color = 'blue';
            % Initialize the environment (create the RanimateTests object)
            app.RanimateInstance = RanimateTests();  
            % Set lamp to green once environment is loaded
            app.StatusLamp.Color = 'green';
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            if ~isempty(app.RanimateInstance)
                % Set lamp to yellow to indicate robot is moving
                app.StatusLamp.Color = 'yellow';
                % Call the moveTrash function to animate the robot
                app.RanimateInstance.moveTrash();
                % Set lamp to green after the robot finishes moving
                app.StatusLamp.Color = 'green';
            else
                % Set lamp to red if the environment is not loaded
                app.StatusLamp.Color = 'red';
                uialert(app.UIFigure, 'Load the environment first!', 'Error');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.EMERGENCYSTOPButton = uibutton(app.UIFigure, 'push');
            app.EMERGENCYSTOPButton.Text = 'EMERGENCY STOP';
            app.StatusBar = slrealtime.ui.control.StatusBar(app.UIFigure);
            app.StatusLampLabel = uilabel(app.UIFigure);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.UIFigure);
            app.ResumeButton = uibutton(app.UIFigure, 'push');
            app.ResumeButton.Text = 'Resume';
            app.NeutralButton = uibutton(app.UIFigure, 'push');
            app.NeutralButton.ButtonPushedFcn = createCallbackFcn(app, @NeutralButtonPushed, true);
            app.NeutralButton.Text = 'Neutral';
            app.Q1SliderLabel = uilabel(app.UIFigure);
            app.Q1SliderLabel.Text = 'Q1';
            app.Q1Slider = uislider(app.UIFigure);
            app.Q1Slider.Limits = [-180 180];
            app.Q2SliderLabel = uilabel(app.UIFigure);
            app.Q2SliderLabel.Text = 'Q2';
            app.Q2Slider = uislider(app.UIFigure);
            app.Q2Slider.Limits = [-180 180];
            app.Q3SliderLabel = uilabel(app.UIFigure);
            app.Q3SliderLabel.Text = 'Q3';
            app.Q3Slider = uislider(app.UIFigure);
            app.Q3Slider.Limits = [-180 180];
            app.Q4SliderLabel = uilabel(app.UIFigure);
            app.Q4SliderLabel.Text = 'Q4';
            app.Q4Slider = uislider(app.UIFigure);
            app.Q4Slider.Limits = [-180 180];
            app.Q5SliderLabel = uilabel(app.UIFigure);
            app.Q5SliderLabel.Text = 'Q5';
            app.Q5Slider = uislider(app.UIFigure);
            app.Q5Slider.Limits = [-180 180];
            app.Q6SliderLabel = uilabel(app.UIFigure);
            app.Q6SliderLabel.Text = 'Q6';
            app.Q6Slider = uislider(app.UIFigure);
            app.Q6Slider.Limits = [-180 180];
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.UIFigure, 'numeric');
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZEditFieldLabel = uilabel(app.UIFigure);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.UIFigure, 'numeric');
            app.RunTaskLabel = uilabel(app.UIFigure);
            app.RunTaskLabel.Text = 'Run Task';
            app.TeachLabel = uilabel(app.UIFigure);
            app.TeachLabel.Text = 'Teach';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = A2_GUI_V3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end