classdef A2_GUI_V5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        ResumeButton         matlab.ui.control.Button
        ReadyLabel           matlab.ui.control.Label
        TeachLabel           matlab.ui.control.Label
        RunTaskLabel         matlab.ui.control.Label
        ZEditField           matlab.ui.control.NumericEditField
        ZEditFieldLabel      matlab.ui.control.Label
        YEditField           matlab.ui.control.NumericEditField
        YEditFieldLabel      matlab.ui.control.Label
        XEditField           matlab.ui.control.NumericEditField
        XEditFieldLabel      matlab.ui.control.Label
        Q6Slider             matlab.ui.control.Slider
        Q6SliderLabel        matlab.ui.control.Label
        Q5Slider             matlab.ui.control.Slider
        Q5SliderLabel        matlab.ui.control.Label
        Q4Slider             matlab.ui.control.Slider
        Q4SliderLabel        matlab.ui.control.Label
        Q3Slider             matlab.ui.control.Slider
        Q3SliderLabel        matlab.ui.control.Label
        Q2Slider             matlab.ui.control.Slider
        Q2SliderLabel        matlab.ui.control.Label
        Q1Slider             matlab.ui.control.Slider
        Q1SliderLabel        matlab.ui.control.Label
        LoadButton           matlab.ui.control.Button
        ResetButton          matlab.ui.control.Button
        StatusLamp           matlab.ui.control.Lamp
        StatusLampLabel      matlab.ui.control.Label
        EMERGENCYSTOPButton  matlab.ui.control.Button
        RunButton            matlab.ui.control.Button
    end

    
    properties (Access = private)
        FinalDemoInstance = FinalDemo();% Hold the Demo object
        SAFETY_FLAG = false  % Safety flag to stop robot (default to false)
    end
    
    methods (Access = public)
        
        function results = robot(app)
            
        end
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            % Set lamp and status to idle state
            app.FinalDemoInstance.placeObjects();
            app.StatusLamp.Color = 'yellow';
            app.ReadyLabel.Text = 'Loading environment';

            % Set lamp and status to show environment is loaded
            app.StatusLamp.Color = 'green';
            app.ReadyLabel.Text = 'Ready to run';
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            app.FinalDemoInstance.moveTrash();
            if app.SAFETY_FLAG
                % If emergency stop is active, prevent movement
                app.ReadyLabel.Text = 'E-STOP active';
                app.StatusLamp.Color = 'red';
                app.FinalDemoInstance.triggeredEstopGui();
                app.FinalDemoInstance.SafetyFlag = true; 
                return;
            end
            
            if ~isempty(app.FinalDemoInstance)
                % Set lamp and status to indicate robot is moving
                app.StatusLamp.Color = 'yellow';
                app.ReadyLabel.Text = 'Robots moving';
                app.SAFETY_FLAG = false;

                % Move robot only if SAFETY_FLAG is false
                if ~app.SAFETY_FLAG

                    % Set lamp and status to indicate robot finished moving
                    app.StatusLamp.Color = 'green';
                    app.ReadyLabel.Text = 'Task complete';
                end

            else
                % Set lamp and status if the environment is not loaded
                app.StatusLamp.Color = 'red';
                app.ReadyLabel.Text = 'Error';
            end
        end

        % Button pushed function: EMERGENCYSTOPButton
        function EMERGENCYSTOPButtonPushed(app, event)
            % Activate emergency stop
            app.SAFETY_FLAG = true;
            app.ReadyLabel.Text = 'E-STOP active';
            app.StatusLamp.Color = 'red';
            %app.FinalDemoInstance.triggeredEstopGui();
            app.FinalDemoInstance.SafetyFlag = true; 
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.SAFETY_FLAG = false;
            app.ReadyLabel.Text = 'System reset';
            app.StatusLamp.Color = 'green';
            app.FinalDemoInstance.SafetyFlag = false; 
        end

        % Button pushed function: ResumeButton
        function ResumeButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.EMERGENCYSTOPButton = uibutton(app.UIFigure, 'push');
            app.EMERGENCYSTOPButton.ButtonPushedFcn = createCallbackFcn(app, @EMERGENCYSTOPButtonPushed, true);
            app.EMERGENCYSTOPButton.Text = 'EMERGENCY STOP';
            app.StatusLampLabel = uilabel(app.UIFigure);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.UIFigure);
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.Q1SliderLabel = uilabel(app.UIFigure);
            app.Q1SliderLabel.Text = 'Q1';
            app.Q1Slider = uislider(app.UIFigure);
            app.Q1Slider.Limits = [-180 180];
            app.Q2SliderLabel = uilabel(app.UIFigure);
            app.Q2SliderLabel.Text = 'Q2';
            app.Q2Slider = uislider(app.UIFigure);
            app.Q2Slider.Limits = [-180 180];
            app.Q3SliderLabel = uilabel(app.UIFigure);
            app.Q3SliderLabel.Text = 'Q3';
            app.Q3Slider = uislider(app.UIFigure);
            app.Q3Slider.Limits = [-180 180];
            app.Q4SliderLabel = uilabel(app.UIFigure);
            app.Q4SliderLabel.Text = 'Q4';
            app.Q4Slider = uislider(app.UIFigure);
            app.Q4Slider.Limits = [-180 180];
            app.Q5SliderLabel = uilabel(app.UIFigure);
            app.Q5SliderLabel.Text = 'Q5';
            app.Q5Slider = uislider(app.UIFigure);
            app.Q5Slider.Limits = [-180 180];
            app.Q6SliderLabel = uilabel(app.UIFigure);
            app.Q6SliderLabel.Text = 'Q6';
            app.Q6Slider = uislider(app.UIFigure);
            app.Q6Slider.Limits = [-180 180];
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.UIFigure, 'numeric');
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZEditFieldLabel = uilabel(app.UIFigure);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.UIFigure, 'numeric');
            app.RunTaskLabel = uilabel(app.UIFigure);
            app.RunTaskLabel.Text = 'Run Task';
            app.TeachLabel = uilabel(app.UIFigure);
            app.TeachLabel.Text = 'Teach';
            app.ReadyLabel = uilabel(app.UIFigure);
            app.ReadyLabel.Text = 'Ready';
            app.ResumeButton = uibutton(app.UIFigure, 'push');
            app.ResumeButton.ButtonPushedFcn = createCallbackFcn(app, @ResumeButtonPushed, true);
            app.ResumeButton.Text = 'Resume';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = A2_GUI_V5

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end