classdef A2_GUI_V6 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        ReadyLabel           matlab.ui.control.Label
        TeachLabel           matlab.ui.control.Label
        RunTaskLabel         matlab.ui.control.Label
        ZEditField           matlab.ui.control.NumericEditField
        ZEditFieldLabel      matlab.ui.control.Label
        YEditField           matlab.ui.control.NumericEditField
        YEditFieldLabel      matlab.ui.control.Label
        XEditField           matlab.ui.control.NumericEditField
        XEditFieldLabel      matlab.ui.control.Label
        Q6Slider             matlab.ui.control.Slider
        Q6SliderLabel        matlab.ui.control.Label
        Q5Slider             matlab.ui.control.Slider
        Q5SliderLabel        matlab.ui.control.Label
        Q4Slider             matlab.ui.control.Slider
        Q4SliderLabel        matlab.ui.control.Label
        Q3Slider             matlab.ui.control.Slider
        Q3SliderLabel        matlab.ui.control.Label
        Q2Slider             matlab.ui.control.Slider
        Q2SliderLabel        matlab.ui.control.Label
        Q1Slider             matlab.ui.control.Slider
        Q1SliderLabel        matlab.ui.control.Label
        LoadButton           matlab.ui.control.Button
        ResetButton          matlab.ui.control.Button
        StatusLamp           matlab.ui.control.Lamp
        StatusLampLabel      matlab.ui.control.Label
        EMERGENCYSTOPButton  matlab.ui.control.Button
        RunButton            matlab.ui.control.Button
    end

    
    properties (Access = private)
        FinalDemoInstance = FinalDemo();% Hold the Demo object
        SAFETY_FLAG = false  % Safety flag to stop robot (default to false)
        
    end


    methods (Access = public)
        
        function results = robot(app)
            
        end
    end
    
    methods (Access = private)

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            % Set lamp and status to idle state
            app.StatusLamp.Color = 'yellow';
            app.ReadyLabel.Text = 'Loading environment';
            app.FinalDemoInstance.placeObjects();

            % Set lamp and status to show environment is loaded
            app.StatusLamp.Color = 'green';
            app.ReadyLabel.Text = 'Ready to run';
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            app.FinalDemoInstance.moveTrash();
            if app.SAFETY_FLAG
                % If emergency stop is active, prevent movement
                app.ReadyLabel.Text = 'E-STOP active';
                app.StatusLamp.Color = 'red';
                app.FinalDemoInstance.triggeredEstopGui();
                app.FinalDemoInstance.SafetyFlag = true; 
                return;
            end
            
            if ~isempty(app.FinalDemoInstance)
                % Set lamp and status to indicate robot is moving
                app.StatusLamp.Color = 'yellow';
                app.ReadyLabel.Text = 'Robots moving';
                app.SAFETY_FLAG = false;

                % Move robot only if SAFETY_FLAG is false
                if ~app.SAFETY_FLAG

                    % Set lamp and status to indicate robot finished moving
                    app.StatusLamp.Color = 'green';
                    app.ReadyLabel.Text = 'Task complete';
                end

            else
                % Set lamp and status if the environment is not loaded
                app.StatusLamp.Color = 'red';
                app.ReadyLabel.Text = 'Error';
            end
        end

        % Button pushed function: EMERGENCYSTOPButton
        function EMERGENCYSTOPButtonPushed(app, event)
            % Activate emergency stop
            app.SAFETY_FLAG = true;
            app.ReadyLabel.Text = 'E-STOP active';
            app.StatusLamp.Color = 'red';
            app.FinalDemoInstance.triggeredEstopGui();
            app.FinalDemoInstance.SafetyFlag = true; 
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.SAFETY_FLAG = false;
            app.ReadyLabel.Text = 'System reset';
            app.StatusLamp.Color = 'green';
            app.FinalDemoInstance.SafetyFlag = false; 
        end

        % Value changed function: Q1Slider
        function Q1SliderValueChanged(app, event)
            q1 = app.Q1Slider.Value;

            if app.SAFETY_FLAG
                % If emergency stop is active, prevent movement
                app.ReadyLabel.Text = 'E-STOP active';
                app.StatusLamp.Color = 'red';
                app.FinalDemoInstance.triggeredEstopGui();
                app.FinalDemoInstance.SafetyFlag = true; 
                return;
            end

            if ~isempty(app.FinalDemoInstance)
                jointAngles = deg2rad([
                    app.Q1Slider.Value, 
                    app.Q2Slider.Value, 
                    app.Q3Slider.Value, 
                    app.Q4Slider.Value, 
                    app.Q5Slider.Value, 
                    app.Q6Slider.Value
                ]);

                app.FinalDemoInstance.teach(jointAngles, 1);
            else
                app.StatusLamp.Color = 'red';
                app.ReadyLabel.Text = 'Error';
            end
        end

        % Value changed function: Q2Slider
        function Q2SliderValueChanged(app, event)
            q2 = app.Q2Slider.Value;

            if app.SAFETY_FLAG
                % If emergency stop is active, prevent movement
                app.ReadyLabel.Text = 'E-STOP active';
                app.StatusLamp.Color = 'red';
                app.FinalDemoInstance.triggeredEstopGui();
                app.FinalDemoInstance.SafetyFlag = true; 
                return;
            end

            if ~isempty(app.FinalDemoInstance)
                jointAngles = deg2rad([
                    app.Q1Slider.Value, 
                    app.Q2Slider.Value, 
                    app.Q3Slider.Value, 
                    app.Q4Slider.Value, 
                    app.Q5Slider.Value, 
                    app.Q6Slider.Value
                ]);

                app.FinalDemoInstance.teach(jointAngles, 2);
            else
                app.StatusLamp.Color = 'red';
                app.ReadyLabel.Text = 'Error';
            end
            
        end

        % Value changed function: Q3Slider
        function Q3SliderValueChanged(app, event)
            q3 = app.Q3Slider.Value;
            
            if app.SAFETY_FLAG
                % If emergency stop is active, prevent movement
                app.ReadyLabel.Text = 'E-STOP active';
                app.StatusLamp.Color = 'red';
                app.FinalDemoInstance.triggeredEstopGui();
                app.FinalDemoInstance.SafetyFlag = true; 
                return;
            end

            if ~isempty(app.FinalDemoInstance)
                jointAngles = deg2rad([
                    app.Q1Slider.Value, 
                    app.Q2Slider.Value, 
                    app.Q3Slider.Value, 
                    app.Q4Slider.Value, 
                    app.Q5Slider.Value, 
                    app.Q6Slider.Value
                ]);

                app.FinalDemoInstance.teach(jointAngles, 3);
            else
                app.StatusLamp.Color = 'red';
                app.ReadyLabel.Text = 'Error';
            end
        end

        % Value changed function: Q4Slider
        function Q4SliderValueChanged(app, event)
            q4 = app.Q4Slider.Value;
            
            if app.SAFETY_FLAG
                % If emergency stop is active, prevent movement
                app.ReadyLabel.Text = 'E-STOP active';
                app.StatusLamp.Color = 'red';
                app.FinalDemoInstance.triggeredEstopGui();
                app.FinalDemoInstance.SafetyFlag = true; 
                return;
            end

            if ~isempty(app.FinalDemoInstance)
                jointAngles = deg2rad([
                    app.Q1Slider.Value, 
                    app.Q2Slider.Value, 
                    app.Q3Slider.Value, 
                    app.Q4Slider.Value, 
                    app.Q5Slider.Value, 
                    app.Q6Slider.Value
                ]);

                app.FinalDemoInstance.teach(jointAngles, 4);
            else
                app.StatusLamp.Color = 'red';
                app.ReadyLabel.Text = 'Error';
            end
        end

        % Value changed function: Q5Slider
        function Q5SliderValueChanged(app, event)
            q5 = app.Q5Slider.Value;
            
            if app.SAFETY_FLAG
                % If emergency stop is active, prevent movement
                app.ReadyLabel.Text = 'E-STOP active';
                app.StatusLamp.Color = 'red';
                app.FinalDemoInstance.triggeredEstopGui();
                app.FinalDemoInstance.SafetyFlag = true; 
                return;
            end

            if ~isempty(app.FinalDemoInstance)
                jointAngles = deg2rad([
                    app.Q1Slider.Value, 
                    app.Q2Slider.Value, 
                    app.Q3Slider.Value, 
                    app.Q4Slider.Value, 
                    app.Q5Slider.Value, 
                    app.Q6Slider.Value
                ]);

                app.FinalDemoInstance.teach(jointAngles, 5);
            else
                app.StatusLamp.Color = 'red';
                app.ReadyLabel.Text = 'Error';
            end
        end

        % Value changed function: Q6Slider
        function Q6SliderValueChanged(app, event)
            q6 = app.Q6Slider.Value;
            
            if app.SAFETY_FLAG
                % If emergency stop is active, prevent movement
                app.ReadyLabel.Text = 'E-STOP active';
                app.StatusLamp.Color = 'red';
                app.FinalDemoInstance.triggeredEstopGui();
                app.FinalDemoInstance.SafetyFlag = true; 
                return;
            end

            if ~isempty(app.FinalDemoInstance)
                jointAngles = deg2rad([
                    app.Q1Slider.Value, 
                    app.Q2Slider.Value, 
                    app.Q3Slider.Value, 
                    app.Q4Slider.Value, 
                    app.Q5Slider.Value, 
                    app.Q6Slider.Value
                ]);

                app.FinalDemoInstance.teach(jointAngles, 6);
            else
                app.StatusLamp.Color = 'red';
                app.ReadyLabel.Text = 'Error';
            end
        end

        % Value changed function: XEditField
        function XEditFieldValueChanged(app, event)
            x = app.XEditField.Value;

            y = app.YEditField.Value;
            z = app.ZEditField.Value;

            if app.SAFETY_FLAG
                % If emergency stop is active, prevent movement
                app.ReadyLabel.Text = 'E-STOP active';
                app.StatusLamp.Color = 'red';
                app.FinalDemoInstance.triggeredEstopGui();
                app.FinalDemoInstance.SafetyFlag = true; 
                return;
            end

            % Call function to move robot to specified Cartesian position
            if ~isempty(app.FinalDemoInstance)
                app.FinalDemoInstance.moveToCartesianPosition(x, y, z);

                % Update status display
                app.StatusLamp.Color = 'green';
                app.ReadyLabel.Text = 'Moved to specified position';
            else
            % Error handling if environment not loaded
            app.StatusLamp.Color = 'red';
            app.ReadyLabel.Text = 'Error';
            end
        end

        % Value changed function: YEditField
        function YEditFieldValueChanged(app, event)
            y = app.YEditField.Value;

            x = app.XEditField.Value;
            z = app.ZEditField.Value;

            if app.SAFETY_FLAG
                % If emergency stop is active, prevent movement
                app.ReadyLabel.Text = 'E-STOP active';
                app.StatusLamp.Color = 'red';
                app.FinalDemoInstance.triggeredEstopGui();
                app.FinalDemoInstance.SafetyFlag = true; 
                return;
            end

            % Call function to move robot to specified Cartesian position
            if ~isempty(app.FinalDemoInstance)
                app.FinalDemoInstance.moveToCartesianPosition(x, y, z);

                % Update status display
                app.StatusLamp.Color = 'green';
                app.ReadyLabel.Text = 'Moved to specified position';
            else
            % Error handling if environment not loaded
            app.StatusLamp.Color = 'red';
            app.ReadyLabel.Text = 'Error';
            end
        end

        % Value changed function: ZEditField
        function ZEditFieldValueChanged(app, event)
            z = app.ZEditField.Value;

            x = app.XEditField.Value;
            y = app.YEditField.Value;

            if app.SAFETY_FLAG
                % If emergency stop is active, prevent movement
                app.ReadyLabel.Text = 'E-STOP active';
                app.StatusLamp.Color = 'red';
                app.FinalDemoInstance.triggeredEstopGui();
                app.FinalDemoInstance.SafetyFlag = true; 
                return;
            end

            % Call function to move robot to specified Cartesian position
            if ~isempty(app.FinalDemoInstance)
                app.FinalDemoInstance.moveToCartesianPosition(x, y, z);

                % Update status display
                app.StatusLamp.Color = 'green';
                app.ReadyLabel.Text = 'Moved to specified position';
            else
            % Error handling if environment not loaded
            app.StatusLamp.Color = 'red';
            app.ReadyLabel.Text = 'Error';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.EMERGENCYSTOPButton = uibutton(app.UIFigure, 'push');
            app.EMERGENCYSTOPButton.ButtonPushedFcn = createCallbackFcn(app, @EMERGENCYSTOPButtonPushed, true);
            app.EMERGENCYSTOPButton.Text = 'EMERGENCY STOP';
            app.StatusLampLabel = uilabel(app.UIFigure);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.UIFigure);
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.Q1SliderLabel = uilabel(app.UIFigure);
            app.Q1SliderLabel.Text = 'Q1';
            app.Q1Slider = uislider(app.UIFigure);
            app.Q1Slider.Limits = [-180 180];
            app.Q1Slider.ValueChangedFcn = createCallbackFcn(app, @Q1SliderValueChanged, true);
            app.Q2SliderLabel = uilabel(app.UIFigure);
            app.Q2SliderLabel.Text = 'Q2';
            app.Q2Slider = uislider(app.UIFigure);
            app.Q2Slider.Limits = [-180 180];
            app.Q2Slider.ValueChangedFcn = createCallbackFcn(app, @Q2SliderValueChanged, true);
            app.Q3SliderLabel = uilabel(app.UIFigure);
            app.Q3SliderLabel.Text = 'Q3';
            app.Q3Slider = uislider(app.UIFigure);
            app.Q3Slider.Limits = [-180 180];
            app.Q3Slider.ValueChangedFcn = createCallbackFcn(app, @Q3SliderValueChanged, true);
            app.Q4SliderLabel = uilabel(app.UIFigure);
            app.Q4SliderLabel.Text = 'Q4';
            app.Q4Slider = uislider(app.UIFigure);
            app.Q4Slider.Limits = [-180 180];
            app.Q4Slider.ValueChangedFcn = createCallbackFcn(app, @Q4SliderValueChanged, true);
            app.Q5SliderLabel = uilabel(app.UIFigure);
            app.Q5SliderLabel.Text = 'Q5';
            app.Q5Slider = uislider(app.UIFigure);
            app.Q5Slider.Limits = [-180 180];
            app.Q5Slider.ValueChangedFcn = createCallbackFcn(app, @Q5SliderValueChanged, true);
            app.Q6SliderLabel = uilabel(app.UIFigure);
            app.Q6SliderLabel.Text = 'Q6';
            app.Q6Slider = uislider(app.UIFigure);
            app.Q6Slider.Limits = [-180 180];
            app.Q6Slider.ValueChangedFcn = createCallbackFcn(app, @Q6SliderValueChanged, true);
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.UIFigure, 'numeric');
            app.XEditField.AllowEmpty = 'on';
            app.XEditField.ValueChangedFcn = createCallbackFcn(app, @XEditFieldValueChanged, true);
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.UIFigure, 'numeric');
            app.YEditField.ValueChangedFcn = createCallbackFcn(app, @YEditFieldValueChanged, true);
            app.ZEditFieldLabel = uilabel(app.UIFigure);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZEditField.ValueChangedFcn = createCallbackFcn(app, @ZEditFieldValueChanged, true);
            app.RunTaskLabel = uilabel(app.UIFigure);
            app.RunTaskLabel.Text = 'Run Task';
            app.TeachLabel = uilabel(app.UIFigure);
            app.TeachLabel.Text = 'Teach';
            app.ReadyLabel = uilabel(app.UIFigure);
            app.ReadyLabel.Text = 'Ready';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = A2_GUI_V6

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end